classdef Apka_Transformatka < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ApkaTransformatkaUIFigure    matlab.ui.Figure
        WgrajzdjcieButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        RysujgdywgranezdjciesposobemspiralnieButton  matlab.ui.control.Button
        RysujgdywgranezdjciesposobemodlewejpionowoButton  matlab.ui.control.Button
        WgrajdaneButton              matlab.ui.control.Button
        SposbzebraniazdjciaLabel     matlab.ui.control.Label
        SposbzebraniazdjciaDropDown  matlab.ui.control.DropDown
        RysujgdywgranezdjciesposobempoliniiButton  matlab.ui.control.Button
        OpnieniemsSliderLabel        matlab.ui.control.Label
        OpnieniemsSlider             matlab.ui.control.Slider
        OffsetEditFieldLabel         matlab.ui.control.Label
        OffsetEditField              matlab.ui.control.NumericEditField
        LiczTransformateButton       matlab.ui.control.Button
        NajpierwwgrajzdjecieLabel    matlab.ui.control.Label
        RysujButton                  matlab.ui.control.Button
        LiniaCheckBox                matlab.ui.control.CheckBox
        LiniaCheckBox_2              matlab.ui.control.CheckBox
        ZrbgifaCheckBox              matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        %% Created by Mateusz Górczany & Karol Szeliga

        nazwa_zdjecia = 'x';
        path = 'x';
        waiter = 0;
        
        cords = 0;
        drawingDane =0;
        radiiDane=0;
        setupDane=0;
        
        cords_lin = 0; 
        
        cords_pion = 0; 
        
        cords_spir = 0; 
        
        sposob = 1;
        
        drawingP =0;
        radiiP=0;
        setupP=0;
        
        drawingL =0;
        radiiL=0;
        setupL=0;
        
        drawingS =0;
        radiiS=0;
        setupS=0;
    end
    
    methods (Access = private)


end %kończy wszystkie funkcje


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WgrajzdjcieButton
        function WgrajzdjcieButtonPushed(app, event)
            cla(app.UIAxes)
            %%
            app.drawingP =0;
            app.radiiP=0;
            app.setupP=0;
        
            app.drawingL =0;
            app.radiiL=0;
            app.setupL=0;
        
            app.drawingS =0;
            app.radiiS=0;
            app.setupS=0;
            %%
%             if app.cords_lin ~= 0 | app.cords_pion ~= 0 | app.cords_spir ~= 0
%                 app.cords_lin = 0;
%                 %app.round_lin = 0;
%                 app.cords_pion = 0; 
%                 app.round_pion = 0;
%                 app.cords_spir = 0; 
%                 app.round_spir = 0; 
%               
%            end
            app.NajpierwwgrajzdjecieLabel.Visible = 'off';
            [app.nazwa_zdjecia,app.path] = uigetfile({'*.jpg';'*.jpeg';'*.png'}, 'Wybierz zdjęcie'); % zapisuje nazwę pliku, który wybierzesz z dialog boxa      
        end

        % Button pushed function: 
        % RysujgdywgranezdjciesposobemspiralnieButton
        function RysujgdywgranezdjciesposobemspiralnieButtonPushed(app, event)
            if app.cords_spir == 0 
                uialert(app.ApkaTransformatkaUIFigure, 'Nie policzono transformaty tym sposobem', 'Błąd', 'Icon', 'warning');
            else
               switch app.LiniaCheckBox_2.Value
                case 1
                    Animateline(app.drawingS,app.radiiS,app.setupS,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                case 0
                    if app.ZrbgifaCheckBox.Value == 0
                        Animate(app.drawingS,app.radiiS,app.setupS,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                    else
                        MakeGif(app.drawingS,app.radiiS,app.setupS,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                    end
                otherwise 
                    disp("Błąd");
               end
            end
        end

        % Value changed function: SposbzebraniazdjciaDropDown
        function SposbzebraniazdjciaDropDownValueChanged(app, event)
            value = app.SposbzebraniazdjciaDropDown.Value;
            if value(1) == "p"
                app.sposob = 1;
            else
                if value(1) == "o"
                    app.sposob = 2;
                else
                    app.sposob = 3;
                end
            end
                
        end

        % Button pushed function: 
        % RysujgdywgranezdjciesposobempoliniiButton
        function RysujgdywgranezdjciesposobempoliniiButtonPushed(app, event)
            if app.cords_lin == 0 
                uialert(app.ApkaTransformatkaUIFigure, 'Nie policzono transformaty tym sposobem', 'Błąd', 'Icon', 'warning');
            else
               switch app.LiniaCheckBox_2.Value
                case 1
                    Animateline(app.drawingL,app.radiiL,app.setupL,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                case 0
                     if app.ZrbgifaCheckBox.Value == 0
                        Animate(app.drawingL,app.radiiL,app.setupL,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                    else
                        MakeGif(app.drawingL,app.radiiL,app.setupL,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                     end
                otherwise 
                    disp("Błąd");
               end
            end
        end

        % Button pushed function: 
        % RysujgdywgranezdjciesposobemodlewejpionowoButton
        function RysujgdywgranezdjciesposobemodlewejpionowoButtonPushed(app, event)
            if app.cords_pion == 0 
                uialert(app.ApkaTransformatkaUIFigure, 'Nie policzono transformaty tym sposobem', 'Błąd', 'Icon', 'warning');
            else
               switch app.LiniaCheckBox_2.Value
                case 1
                    Animateline(app.drawingP,app.radiiP,app.setupP,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                case 0
                    if app.ZrbgifaCheckBox.Value == 0
                        Animate(app.drawingP,app.radiiP,app.setupP,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                    else
                        MakeGif(app.drawingP,app.radiiP,app.setupP,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                    end
                otherwise 
                    disp("Błąd");
               end
            end
        end

        % Value changed function: OpnieniemsSlider
        function OpnieniemsSliderValueChanged(app, event)
            %value = app.OpnieniemsSlider.Value;
            
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
          %  value = app.OffsetEditField.Value;
            
        end

        % Button pushed function: LiczTransformateButton
        function LiczTransformateButtonPushed(app, event)
            cla(app.UIAxes)
            %cla;
            %cla(app.ApkaTransformatkaUIFigure);
            
        
        app.cords = 0;
        app.drawingDane =0;
        app.radiiDane=0;
        app.setupDane=0;
        
        app.cords_lin = 0; 
        
        app.cords_pion = 0; 
        
        app.cords_spir = 0; 
                
        app.drawingP =0;
        app.radiiP=0;
        app.setupP=0;
        
        app.drawingL =0;
        app.radiiL=0;
        app.setupL=0;
        
        app.drawingS =0;
        app.radiiS=0;
        app.setupS=0;
            
        if app.nazwa_zdjecia == 'x'    
           app.NajpierwwgrajzdjecieLabel.Visible = 'on';
        else
            if app.sposob == 1
                app.cords_lin = LadowaniePoLinii(app.nazwa_zdjecia,app.path);
                
                if isequal(app.cords_lin, [0,0])
                    uialert(app.ApkaTransformatkaUIFigure, 'Zbyt dużo pikseli. Wybierz ładowanie od lewej pionowo', 'Błąd');
                    return
                end
                 app.waiter = uiprogressdlg(app.ApkaTransformatkaUIFigure,'Title','Komunikat',...
                     'Message','Trwa obliczanie','Indeterminate','on');
                plot(app.UIAxes,app.cords_lin(:,1),app.cords_lin(:,2),'.');
                [app.drawingL,app.radiiL, app.setupL] = CalculationsFourier(app.cords_lin);
                %plot(app.UIAxes,app.cords_lin(:,1),app.cords_lin(:,2),'.');
            else
                if app.sposob == 2
                    app.waiter = uiprogressdlg(app.ApkaTransformatkaUIFigure,'Title','Komunikat',...
                     'Message','Trwa obliczanie','Indeterminate','on');
                    app.cords_pion = LadowanieProste(app.nazwa_zdjecia,app.path);
                    %plot(app.UIAxes,app.cords_pion(:,1),app.cords_pion(:,2),'.');                    
                    [app.drawingP,app.radiiP, app.setupP] = CalculationsFourier(app.cords_pion);
                    plot(app.UIAxes,app.cords_pion(:,1),app.cords_pion(:,2),'.');
                else
                    app.cords_spir = LadowanieSpiralne(app.nazwa_zdjecia,app.path);
                    %plot(app.UIAxes,app.cords_spir(:,1),app.cords_spir(:,2),'.');
                    
                    if isequal(app.cords_spir, [0,0])
                        uialert(app.ApkaTransformatkaUIFigure, 'Zbyt dużo pikseli. Wybierz ładowanie od lewej pionowo', 'Błąd');
                        return
                    end
                    app.waiter = uiprogressdlg(app.ApkaTransformatkaUIFigure,'Title','Komunikat',...
                     'Message','Trwa obliczanie','Indeterminate','on');
                    
                    [app.drawingS,app.radiiS, app.setupS] = CalculationsFourier(app.cords_spir);
                    plot(app.UIAxes,app.cords_spir(:,1),app.cords_spir(:,2),'.');
                end
            end
        end
        close(app.waiter);
        end

        % Button pushed function: WgrajdaneButton
        function WgrajdaneButtonPushed(app, event)
            %% Kasowanie
            
        app.cords = 0;
        app.drawingDane =0;
        app.radiiDane=0;
        app.setupDane=0;
        
        app.cords_lin = 0; 
        
        app.cords_pion = 0; 
        
        app.cords_spir = 0; 
                
        app.drawingP =0;
        app.radiiP=0;
        app.setupP=0;
        
        app.drawingL =0;
        app.radiiL=0;
        app.setupL=0;
        
        app.drawingS =0;
        app.radiiS=0;
        app.setupS=0;
            
            
            [a,pathy] = uigetfile('*.txt', 'Wybierz dane w formacie txt'); % zapisuje nazwę pliku, który wybierzesz z dialog boxa
            s = importdata(fullfile(pathy,a)); % load data
          
            app.cords = [s(:,1),s(:,2)];

            plot(app.UIAxes,s(:,1),s(:,2),'.');
            [app.drawingDane,app.radiiDane, app.setupDane] = CalculationsFourier(app.cords);

        end

        % Button pushed function: RysujButton
        function RysujButtonPushed(app, event)
            if app.cords == 0 
                uialert(app.ApkaTransformatkaUIFigure, 'Nie wgrano danych', 'Błąd');
                return
            end
            
            if app.LiniaCheckBox.Value == 0
                if app.ZrbgifaCheckBox.Value == 0
                        Animate(app.drawingDane,app.radiiDane, app.setupDane,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                else
                        MakeGif(app.drawingDane,app.radiiDane, app.setupDane,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                end
            else
                if app.ZrbgifaCheckBox.Value == 0
                        Animateline(app.drawingDane,app.radiiDane, app.setupDane,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                else
                        MakeGifline(app.drawingDane,app.radiiDane, app.setupDane,app.OffsetEditField.Value, app.OpnieniemsSlider.Value);
                end
            end
        end

        % Value changed function: LiniaCheckBox
        function LiniaCheckBoxValueChanged(app, event)
            %valuecheckbox = app.LiniaCheckBox.Value;
            
        end

        % Value changed function: LiniaCheckBox_2
        function LiniaCheckBox_2ValueChanged(app, event)
            %valuecheckbox2 = app.LiniaCheckBox_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ApkaTransformatkaUIFigure = uifigure('Visible', 'off');
            app.ApkaTransformatkaUIFigure.Name = 'Apka Transformatka';
            app.WgrajzdjcieButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.WgrajzdjcieButton.ButtonPushedFcn = createCallbackFcn(app, @WgrajzdjcieButtonPushed, true);
            app.WgrajzdjcieButton.Text = {'Wgraj zdjęcie '; ''};
            app.UIAxes = uiaxes(app.ApkaTransformatkaUIFigure);
            title(app.UIAxes, 'Zaimportowany rysunek pojawi się po policzeniu transformaty!')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RysujgdywgranezdjciesposobemspiralnieButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.RysujgdywgranezdjciesposobemspiralnieButton.ButtonPushedFcn = createCallbackFcn(app, @RysujgdywgranezdjciesposobemspiralnieButtonPushed, true);
            app.RysujgdywgranezdjciesposobemspiralnieButton.Text = {'Rysuj '; '(gdy wgrane zdjęcie '; 'sposobem spiralnie)'};
            app.RysujgdywgranezdjciesposobemodlewejpionowoButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.RysujgdywgranezdjciesposobemodlewejpionowoButton.ButtonPushedFcn = createCallbackFcn(app, @RysujgdywgranezdjciesposobemodlewejpionowoButtonPushed, true);
            app.RysujgdywgranezdjciesposobemodlewejpionowoButton.Text = {'Rysuj '; '(gdy wgrane zdjęcie '; 'sposobem od lewej '; 'pionowo )'; ''};
            app.WgrajdaneButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.WgrajdaneButton.ButtonPushedFcn = createCallbackFcn(app, @WgrajdaneButtonPushed, true);
            app.WgrajdaneButton.Text = 'Wgraj dane';
            app.SposbzebraniazdjciaLabel = uilabel(app.ApkaTransformatkaUIFigure);
            app.SposbzebraniazdjciaLabel.Text = 'Sposób zebrania zdjęcia';
            app.SposbzebraniazdjciaDropDown = uidropdown(app.ApkaTransformatkaUIFigure);
            app.SposbzebraniazdjciaDropDown.Items = {'po liniach', 'od lewej pionowo', 'spiralnie'};
            app.SposbzebraniazdjciaDropDown.ValueChangedFcn = createCallbackFcn(app, @SposbzebraniazdjciaDropDownValueChanged, true);
            app.SposbzebraniazdjciaDropDown.Value = 'po liniach';
            app.RysujgdywgranezdjciesposobempoliniiButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.RysujgdywgranezdjciesposobempoliniiButton.ButtonPushedFcn = createCallbackFcn(app, @RysujgdywgranezdjciesposobempoliniiButtonPushed, true);
            app.RysujgdywgranezdjciesposobempoliniiButton.Text = {'Rysuj '; '(gdy wgrane zdjęcie '; 'sposobem po linii)'};
            app.OpnieniemsSliderLabel = uilabel(app.ApkaTransformatkaUIFigure);
            app.OpnieniemsSliderLabel.Text = {'Opóźnienie'; '(ms)'};
            app.OpnieniemsSlider = uislider(app.ApkaTransformatkaUIFigure);
            app.OpnieniemsSlider.Limits = [0 1000];
            app.OpnieniemsSlider.ValueChangedFcn = createCallbackFcn(app, @OpnieniemsSliderValueChanged, true);
            app.OffsetEditFieldLabel = uilabel(app.ApkaTransformatkaUIFigure);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.ApkaTransformatkaUIFigure, 'numeric');
            app.OffsetEditField.Limits = [0 1000];
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.OffsetEditField.Value = 10;
            app.LiczTransformateButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.LiczTransformateButton.ButtonPushedFcn = createCallbackFcn(app, @LiczTransformateButtonPushed, true);
            app.LiczTransformateButton.Text = 'Licz Transformate';
            app.NajpierwwgrajzdjecieLabel = uilabel(app.ApkaTransformatkaUIFigure);
            app.NajpierwwgrajzdjecieLabel.Visible = 'off';
            app.NajpierwwgrajzdjecieLabel.Text = 'Najpierw wgraj zdjecie!';
            app.RysujButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.RysujButton.ButtonPushedFcn = createCallbackFcn(app, @RysujButtonPushed, true);
            app.RysujButton.Text = 'Rysuj';
            app.LiniaCheckBox = uicheckbox(app.ApkaTransformatkaUIFigure);
            app.LiniaCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiniaCheckBoxValueChanged, true);
            app.LiniaCheckBox.Text = 'Linia';
            app.LiniaCheckBox_2 = uicheckbox(app.ApkaTransformatkaUIFigure);
            app.LiniaCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LiniaCheckBox_2ValueChanged, true);
            app.LiniaCheckBox_2.Text = 'Linia';
            app.ZrbgifaCheckBox = uicheckbox(app.ApkaTransformatkaUIFigure);
            app.ZrbgifaCheckBox.Text = 'Zrób gifa';
            % Show the figure after all components are created
            app.ApkaTransformatkaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Apka_Transformatka

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ApkaTransformatkaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ApkaTransformatkaUIFigure)
        end
    end
end